classdef FetchBotRecyclesGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GetJointStatesButton           matlab.ui.control.Button
        MoveArmtoCartesionButton       matlab.ui.control.Button
        MoveArmButton                  matlab.ui.control.Button
        SetupButton                    matlab.ui.control.Button
        GetEECoordinatesButton         matlab.ui.control.Button
        STARTButton                    matlab.ui.control.Button
        L8WRISTROLLPrismaticSlider     matlab.ui.control.Slider
        L8WRISTROLLPrismaticSliderLabel  matlab.ui.control.Label
        L7WRISTFLEXSlider              matlab.ui.control.Slider
        L7WRISTFLEXSliderLabel         matlab.ui.control.Label
        L6FOREARMPrismaticSlider       matlab.ui.control.Slider
        L6FOREARMPrismaticSliderLabel  matlab.ui.control.Label
        L5ELBOWFLEXSlider              matlab.ui.control.Slider
        L5ELBOWFLEXSliderLabel         matlab.ui.control.Label
        L4UPPERARMPrismaticSlider      matlab.ui.control.Slider
        L4UPPERARMLabel                matlab.ui.control.Label
        L3SHOULDERLIFTSlider           matlab.ui.control.Slider
        L3SHOULDERLIFTSliderLabel      matlab.ui.control.Label
        JOINTMOVEMENTLabel             matlab.ui.control.Label
        L2SHOULDERPANSlider            matlab.ui.control.Slider
        L2SHOULDERPANLabel             matlab.ui.control.Label
        L1TORSOLIFTmmSlider            matlab.ui.control.Slider
        L1TORSOLIFTmmSliderLabel       matlab.ui.control.Label
        CARTESIANMOVEMENTLabel         matlab.ui.control.Label
        ZSpinner                       matlab.ui.control.Spinner
        ZLabel                         matlab.ui.control.Label
        YSpinner                       matlab.ui.control.Spinner
        YLabel                         matlab.ui.control.Label
        XSpinner                       matlab.ui.control.Spinner
        XLabel                         matlab.ui.control.Label
        FETCHBOTRECYCLESGUILabel       matlab.ui.control.Label
        ResumeButton                   matlab.ui.control.Button
        ESTOPButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        EstopState = false; % Estop not pressed intially
        taskCompleted = false;
%         simulation;
%         Simulation; 
%         robotFetch;
%         headCamera;
%         Recycle;
        EE_X;
        EE_Y;
        EE_Z;
        qGoal = [0 0 0 0 0 0 0 0];
        jointStatesUpdated = false;
        JointCartUpdated = false; 

    end
    properties (Constant)
        controller= appControl();

    end

    methods (Access = private)

        function updateXYZ(app)

            app.XSpinner.Value = app.EE_X; 
            app.YSpinner.Value = app.EE_Y; 
            app.ZSpinner.Value = app.EE_Z; 
            app.jointStatesUpdated = false;
            

        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SetupButton
        function SetupButtonPushed(app, event)
            clf; clc; clear all;
            app.controller = appControl(); 
            app.jointStatesUpdated = false;
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            % START PROGRAM
            app.controller.Recycle();
            app.jointStatesUpdated = false;
        end

        % Button pushed function: ESTOPButton
        function ESTOPButtonPushed(app, event)
            %EMERGENCY STOP
            %app.EstopState = true;
            %uiwait(app.STARTButton); 
            uiwait();
            app.jointStatesUpdated = false;

        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            uiresume()
            app.jointStatesUpdated = false;

        end

        % Button pushed function: GetEECoordinatesButton
        function GetEECoordinatesButtonPushed(app, event)
            app.controller.simulation.getFkine();
            app.EE_X = app.controller.simulation.EE_X;
            app.EE_Y = app.controller.simulation.EE_Y;
            app.EE_Z = app.controller.simulation.EE_Z;

            app.updateXYZ()

            app.JointCartUpdated = true; 

            %disp(app.EE_X)
        end

        % Value changed function: XSpinner
        function XSpinnerValueChanged(app, event)
            value = app.XSpinner.Value;
            app.jointStatesUpdated = false;
            
        end

        % Value changed function: YSpinner
        function YSpinnerValueChanged(app, event)
            value = app.YSpinner.Value;
            app.jointStatesUpdated = false;
            
        end

        % Value changed function: ZSpinner
        function ZSpinnerValueChanged(app, event)
            value = app.ZSpinner.Value;
            app.jointStatesUpdated = false;
            
        end

        % Button pushed function: MoveArmtoCartesionButton
        function MoveArmtoCartesionButtonPushed(app, event)
            
            if app.JointCartUpdated == true
                % Update q current
                app.controller.simulation.getPos();
                qCurrent = app.controller.simulation.currentQ; 
                % updates current ee position
                app.controller.simulation.getFkine();
                EEPosCurrent = [app.EE_X app.EE_Y app.EE_Z];
                %Updates ee position goal
                EEPosGoal = [app.XSpinner.Value app.YSpinner.Value app.ZSpinner.Value];

                % use RMRC function to cal qgoal and moves arm

                d = 0.0;                                            %desired distance from end-effector to target
                totalTime = 1;                                      %total time
                deltaT = 0.02;                                      %frequency
                p1 = EEPosCurrent;                                  %initial point (last waypoint)
                p2 = EEPosGoal;                                     %target point (object)
                q_guess = qCurrent;                                  %initial guess for joint angles (last q)
                [qMatrix, posError, angleError] = ...               %return q to object, position error and angle error for plotting
                solveRMRC(app.controller.simulation.robotFetch.model,p1,p2,q_guess,totalTime,deltaT,d);  %solve RMRC using parameters above

                app.controller.simulation.MoveArm(qMatrix(end,:)) % moves the arm
                app.controller.simulation.getPos();%sets the class to get pos


            end

        end

        % Button pushed function: GetJointStatesButton
        function GetJointStatesButtonPushed(app, event)
            app.jointStatesUpdated = true;
            app.controller.simulation.getPos();
            qCurrent = app.controller.simulation.currentQ; 
            % Set the sliders to the current value
            app.L1TORSOLIFTmmSlider.Value = qCurrent(1);
            % covert from rads to degrees
            app.L2SHOULDERPANSlider.Value        = rad2deg(qCurrent(2));
            app.L3SHOULDERLIFTSlider.Value       = rad2deg(qCurrent(3));
            app.L4UPPERARMPrismaticSlider.Value  = rad2deg(qCurrent(4));
            app.L5ELBOWFLEXSlider.Value          = rad2deg(qCurrent(5));
            app.L6FOREARMPrismaticSlider.Value   = rad2deg(qCurrent(6));
            app.L7WRISTFLEXSlider.Value          = rad2deg(qCurrent(7));
            app.L8WRISTROLLPrismaticSlider.Value = rad2deg(qCurrent(8));


        end

        % Button pushed function: MoveArmButton
        function MoveArmButtonPushed(app, event)
            app.controller.simulation.getPos();
            
            if app.jointStatesUpdated == true
                %qCurrent = app.controller.simulation.currentQ; 
                app.qGoal(1) = (app.L1TORSOLIFTmmSlider.Value/1000); % converts from m to mm
                
                %covert deg 2 rad
                app.qGoal(2) = deg2rad(app.L2SHOULDERPANSlider.Value);
                app.qGoal(3) = deg2rad(app.L3SHOULDERLIFTSlider.Value);
                app.qGoal(4) = deg2rad(app.L4UPPERARMPrismaticSlider.Value);
                app.qGoal(5) = deg2rad(app.L5ELBOWFLEXSlider.Value);
                app.qGoal(6) = deg2rad(app.L6FOREARMPrismaticSlider.Value);
                app.qGoal(7) = deg2rad(app.L7WRISTFLEXSlider.Value);
                app.qGoal(8) = deg2rad(app.L8WRISTROLLPrismaticSlider.Value);


                app.controller.simulation.MoveArm(app.qGoal) % moves the arm
                app.controller.simulation.getPos();%sets the class to get pos
            end


        end

        % Value changed function: L1TORSOLIFTmmSlider
        function L1TORSOLIFTmmSliderValueChanged(app, event)
            value = app.L1TORSOLIFTmmSlider.Value;
            app.controller.simulation.getPos();  %sets the class to get pos
             
            qCurrent = app.controller.simulation.currentQ; 
            qCurrent(1) = (value/1000); % converts from m to mm
            app.qGoal(1) = qCurrent(1); 
            %app.controller.simulation.MoveArm(qCurrent) % moves the arm
            %app.controller.simulation.getPos();%sets the class to get pos

            
        end

        % Value changed function: L2SHOULDERPANSlider
        function L2SHOULDERPANSliderValueChanged(app, event)
            value = app.L2SHOULDERPANSlider.Value;
            
        end

        % Value changed function: L3SHOULDERLIFTSlider
        function L3SHOULDERLIFTSliderValueChanged(app, event)
            value = app.L3SHOULDERLIFTSlider.Value;
            
        end

        % Value changed function: L4UPPERARMPrismaticSlider
        function L4UPPERARMPrismaticSliderValueChanged(app, event)
            value = app.L4UPPERARMPrismaticSlider.Value;
            
        end

        % Value changed function: L5ELBOWFLEXSlider
        function L5ELBOWFLEXSliderValueChanged(app, event)
            value = app.L5ELBOWFLEXSlider.Value;
            
        end

        % Value changed function: L6FOREARMPrismaticSlider
        function L6FOREARMPrismaticSliderValueChanged(app, event)
            value = app.L6FOREARMPrismaticSlider.Value;
            
        end

        % Value changed function: L7WRISTFLEXSlider
        function L7WRISTFLEXSliderValueChanged(app, event)
            value = app.L7WRISTFLEXSlider.Value;
            
        end

        % Value changed function: L8WRISTROLLPrismaticSlider
        function L8WRISTROLLPrismaticSliderValueChanged(app, event)
            value = app.L8WRISTROLLPrismaticSlider.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPButtonPushed, true);
            app.ESTOPButton.Text = 'E-STOP';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.FETCHBOTRECYCLESGUILabel = uilabel(app.UIFigure);
            app.FETCHBOTRECYCLESGUILabel.Text = {'FETCHBOT RECYCLES GUI'; ''};
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X  ';
            app.XSpinner = uispinner(app.UIFigure);
            app.XSpinner.Step = 0.1;
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Text = 'Y  ';
            app.YSpinner = uispinner(app.UIFigure);
            app.YSpinner.Step = 0.1;
            app.YSpinner.ValueChangedFcn = createCallbackFcn(app, @YSpinnerValueChanged, true);
            app.ZLabel = uilabel(app.UIFigure);
            app.ZLabel.Text = 'Z  ';
            app.ZSpinner = uispinner(app.UIFigure);
            app.ZSpinner.Step = 0.1;
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @ZSpinnerValueChanged, true);
            app.CARTESIANMOVEMENTLabel = uilabel(app.UIFigure);
            app.CARTESIANMOVEMENTLabel.Text = {'CARTESIAN'; 'MOVEMENT'};
            app.L1TORSOLIFTmmSliderLabel = uilabel(app.UIFigure);
            app.L1TORSOLIFTmmSliderLabel.Text = {'L1'; 'TORSO LIFT (mm)'; ''};
            app.L1TORSOLIFTmmSlider = uislider(app.UIFigure);
            app.L1TORSOLIFTmmSlider.Limits = [0 400];
            app.L1TORSOLIFTmmSlider.MajorTicks = [0 100 200 300 400];
            app.L1TORSOLIFTmmSlider.ValueChangedFcn = createCallbackFcn(app, @L1TORSOLIFTmmSliderValueChanged, true);
            app.L1TORSOLIFTmmSlider.MinorTicks = [0 50 100 150 200 250 300 350];
            app.L2SHOULDERPANLabel = uilabel(app.UIFigure);
            app.L2SHOULDERPANLabel.Text = {'L2 '; 'SHOULDER'; 'PAN'; ''};
            app.L2SHOULDERPANSlider = uislider(app.UIFigure);
            app.L2SHOULDERPANSlider.Limits = [-92 92];
            app.L2SHOULDERPANSlider.MajorTicks = [-92 -46 0 46 92];
            app.L2SHOULDERPANSlider.ValueChangedFcn = createCallbackFcn(app, @L2SHOULDERPANSliderValueChanged, true);
            app.L2SHOULDERPANSlider.MinorTicks = [-92 -69 -46 -23 0 23 46 69 92];
            app.JOINTMOVEMENTLabel = uilabel(app.UIFigure);
            app.JOINTMOVEMENTLabel.Text = {'JOINT'; 'MOVEMENT'};
            app.L3SHOULDERLIFTSliderLabel = uilabel(app.UIFigure);
            app.L3SHOULDERLIFTSliderLabel.Text = {'L3 '; 'SHOULDER'; 'LIFT'; ''};
            app.L3SHOULDERLIFTSlider = uislider(app.UIFigure);
            app.L3SHOULDERLIFTSlider.Limits = [-70 87];
            app.L3SHOULDERLIFTSlider.MajorTicks = [-70 0 87];
            app.L3SHOULDERLIFTSlider.ValueChangedFcn = createCallbackFcn(app, @L3SHOULDERLIFTSliderValueChanged, true);
            app.L3SHOULDERLIFTSlider.MinorTicks = [-70 -35 0 45 87];
            app.L4UPPERARMLabel = uilabel(app.UIFigure);
            app.L4UPPERARMLabel.Text = {'L4'; 'UPPER'; 'ARM'; '(Prismatic)'; ''; ''};
            app.L4UPPERARMPrismaticSlider = uislider(app.UIFigure);
            app.L4UPPERARMPrismaticSlider.Limits = [-360 360];
            app.L4UPPERARMPrismaticSlider.MajorTicks = [-360 -180 0 180 360];
            app.L4UPPERARMPrismaticSlider.ValueChangedFcn = createCallbackFcn(app, @L4UPPERARMPrismaticSliderValueChanged, true);
            app.L4UPPERARMPrismaticSlider.MinorTicks = [-360 -270 -180 -90 0 90 180 270 360];
            app.L5ELBOWFLEXSliderLabel = uilabel(app.UIFigure);
            app.L5ELBOWFLEXSliderLabel.Text = {'L5 '; 'ELBOW'; 'FLEX'; ''};
            app.L5ELBOWFLEXSlider = uislider(app.UIFigure);
            app.L5ELBOWFLEXSlider.Limits = [-129 129];
            app.L5ELBOWFLEXSlider.MajorTicks = [-129 -65 0 65 129];
            app.L5ELBOWFLEXSlider.ValueChangedFcn = createCallbackFcn(app, @L5ELBOWFLEXSliderValueChanged, true);
            app.L5ELBOWFLEXSlider.MinorTicks = [-129 -97 -65 -33 0 33 65 97 129];
            app.L6FOREARMPrismaticSliderLabel = uilabel(app.UIFigure);
            app.L6FOREARMPrismaticSliderLabel.Text = {'L6 '; 'FOREARM'; '(Prismatic)'; ''; ''};
            app.L6FOREARMPrismaticSlider = uislider(app.UIFigure);
            app.L6FOREARMPrismaticSlider.Limits = [-360 360];
            app.L6FOREARMPrismaticSlider.MajorTicks = [-360 -180 0 180 360];
            app.L6FOREARMPrismaticSlider.ValueChangedFcn = createCallbackFcn(app, @L6FOREARMPrismaticSliderValueChanged, true);
            app.L6FOREARMPrismaticSlider.MinorTicks = [-360 -270 -180 -90 0 90 180 270 360];
            app.L7WRISTFLEXSliderLabel = uilabel(app.UIFigure);
            app.L7WRISTFLEXSliderLabel.Text = {'L7 '; 'WRIST'; 'FLEX'; ''; ''};
            app.L7WRISTFLEXSlider = uislider(app.UIFigure);
            app.L7WRISTFLEXSlider.Limits = [-125 125];
            app.L7WRISTFLEXSlider.MajorTicks = [-125 -63 0 63 125];
            app.L7WRISTFLEXSlider.ValueChangedFcn = createCallbackFcn(app, @L7WRISTFLEXSliderValueChanged, true);
            app.L7WRISTFLEXSlider.MinorTicks = [-125 -94 -63 -32 0 32 63 94 125];
            app.L8WRISTROLLPrismaticSliderLabel = uilabel(app.UIFigure);
            app.L8WRISTROLLPrismaticSliderLabel.Text = {'L8 '; 'WRIST'; 'ROLL'; '(Prismatic)'; ''};
            app.L8WRISTROLLPrismaticSlider = uislider(app.UIFigure);
            app.L8WRISTROLLPrismaticSlider.Limits = [-360 360];
            app.L8WRISTROLLPrismaticSlider.MajorTicks = [-360 -180 0 180 360];
            app.L8WRISTROLLPrismaticSlider.ValueChangedFcn = createCallbackFcn(app, @L8WRISTROLLPrismaticSliderValueChanged, true);
            app.L8WRISTROLLPrismaticSlider.MinorTicks = [-360 -270 -180 -90 0 90 180 270 360];
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.GetEECoordinatesButton = uibutton(app.UIFigure, 'push');
            app.GetEECoordinatesButton.ButtonPushedFcn = createCallbackFcn(app, @GetEECoordinatesButtonPushed, true);
            app.GetEECoordinatesButton.Text = 'Get EE Coordinates';
            app.SetupButton = uibutton(app.UIFigure, 'push');
            app.SetupButton.ButtonPushedFcn = createCallbackFcn(app, @SetupButtonPushed, true);
            app.SetupButton.Text = 'Setup';
            app.MoveArmButton = uibutton(app.UIFigure, 'push');
            app.MoveArmButton.ButtonPushedFcn = createCallbackFcn(app, @MoveArmButtonPushed, true);
            app.MoveArmButton.Text = 'Move Arm';
            app.MoveArmtoCartesionButton = uibutton(app.UIFigure, 'push');
            app.MoveArmtoCartesionButton.ButtonPushedFcn = createCallbackFcn(app, @MoveArmtoCartesionButtonPushed, true);
            app.MoveArmtoCartesionButton.Text = 'Move Arm to Cartesion';
            app.GetJointStatesButton = uibutton(app.UIFigure, 'push');
            app.GetJointStatesButton.ButtonPushedFcn = createCallbackFcn(app, @GetJointStatesButtonPushed, true);
            app.GetJointStatesButton.Text = 'Get Joint States';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FetchBotRecyclesGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end